classdef ConnectoMEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConnectoMEGToolboxUIFigure      matlab.ui.Figure
        PreferencesMenu                 matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        OpenGeneticsMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        basicTab                        matlab.ui.container.Tab
        geneticanalysisTab              matlab.ui.container.Tab
        GOIListBox                      matlab.ui.control.ListBox
        GOIselectedLabel                matlab.ui.control.Label
        GOIEditField                    matlab.ui.control.EditField
        GOIEditFieldLabel               matlab.ui.control.Label
        ShowgeneticcorrelationButton    matlab.ui.control.Button
        AtlasButtonGroup                matlab.ui.container.ButtonGroup
        DesikanKillianyButton           matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        ComparedifferencesTab           matlab.ui.container.Tab
        UpdatefromcenterpanelButton     matlab.ui.control.Button
        CohortDropDown_2                matlab.ui.control.DropDown
        Conn_metricDropDown_2           matlab.ui.control.DropDown
        FOIDropDown_2                   matlab.ui.control.DropDown
        AtlasDropDown_2                 matlab.ui.control.DropDown
        LabelDropDown_2                 matlab.ui.control.DropDown
        ShowwithincohortspatialtmapButton  matlab.ui.control.Button
        ShowpercentagedifferenceButton  matlab.ui.control.Button
        ShowwithincohortspectraltmapButton  matlab.ui.control.Button
        ShowacrosscohorttmapButton      matlab.ui.control.Button
        UpdatetmaptopmapButton          matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        CenterPanel                     matlab.ui.container.Panel
        ToolboxLabel                    matlab.ui.control.Label
        CohortDropDown                  matlab.ui.control.DropDown
        Conn_metricDropDown             matlab.ui.control.DropDown
        FOIDropDown                     matlab.ui.control.DropDown
        InteractivemodeButton           matlab.ui.control.Button
        ROIButtonGroup                  matlab.ui.container.ButtonGroup
        ImportAtlasButton               matlab.ui.control.Button
        allROIs                         matlab.ui.control.Button
        defineMNImatrix                 matlab.ui.control.Button
        definemask                      matlab.ui.control.Button
        AtlasDropDown                   matlab.ui.control.DropDown
        LabelDropDown                   matlab.ui.control.DropDown
        MNIx                            matlab.ui.control.NumericEditField
        MNIy                            matlab.ui.control.NumericEditField
        MNIz                            matlab.ui.control.NumericEditField
        AtlasLabel                      matlab.ui.control.Label
        MNIxyzEditFieldLabel            matlab.ui.control.Label
        ROIsLabel                       matlab.ui.control.Label
        ShownormativeconnectivityButton  matlab.ui.control.Button
        CohortDropDownLabel             matlab.ui.control.Label
        FrequencybandofinterestLabel    matlab.ui.control.Label
        ConnectivitymetricDropDownLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Smoothening                     matlab.ui.control.CheckBox
        LoadintoworkspaceButton         matlab.ui.control.Button
        ExportcurrentresultsButton      matlab.ui.control.Button
        UpdateandrefreshButton          matlab.ui.control.Button
        AmountofnodesButtonGroup        matlab.ui.container.ButtonGroup
        symmetricalmeshshuffledLabel    matlab.ui.control.Label
        Button_1018_shuffled            matlab.ui.control.RadioButton
        Button_1028                     matlab.ui.control.RadioButton
        Button_186                      matlab.ui.control.RadioButton
        PlotsettingsLabel               matlab.ui.control.Label
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        connectogramButton              matlab.ui.control.RadioButton
        matrixButton                    matlab.ui.control.RadioButton
        spectralplotButton              matlab.ui.control.RadioButton
        wholebrainconnectivityButton    matlab.ui.control.RadioButton
        Significant                     matlab.ui.control.CheckBox
        SpatialNormalization            matlab.ui.control.CheckBox
        outputDir                       matlab.ui.control.EditField
        outputdirectoryLabel            matlab.ui.control.Label
        dirBrowseButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        %cd('C:\Users\Jonathan\Charité - Universitätsmedizin Berlin\Interventional Cognitive Neuromodulation - PROJECT MEG Connectomics\3_TUTTI\rawdataCM')
        %outputDir = pwd % Description
        cohort = 'HCP_healthy'%= app.CohortDropDown.Value.ItemsData
        
        FOIs       = 'theta'% = str2double(app.FOIDropDown.Value)
        cond      ='healthy'
        dataset    = 'HCP'
        norm = '';
        conn_nodes = 186;
        
        gene_abbreviations = readtable('gene_abbreviations.csv');
        
        %ctx %= 'wjn_mni_cortex';       % Cortex
    end
    
    properties (Access = public)
        CMfreq % Description
        CMroi
        CMavg
        freq
        ROIs
        ba
        ba_pvals = 0
        ba_tvals = 0
        ba_trans
        ba_trans_prev
        ba_trans_perc
        ba_tvals_trans
        ba_pvals_trans
        conn_metric = 'coh'
        signif = true;
        DialogAppConnectoMeg = gobjects(0);
        DialogAppConnectoMegDiff = gobjects(0);
        DialogAppAddAtlas = gobjects(0);
        mesh2meshmap
        mesh2labels
        filename = '';
    end
    methods (Access = public)
        function updateConnectoMEG(app)
            if strcmp(app.cohort,'HCP_healthy')
                app.dataset   = 'HCP';
                app.cond = 'healthy';
            elseif strcmp(app.cohort,'OMEGA_healthy')
                app.dataset   = 'OMEGA';
                app.cond = 'healthy';
            elseif strcmp(app.cohort,'OMEGA_PD')
                app.dataset   = 'OMEGA';
                app.cond = 'PD';
            end

            app.FOIs                =   app.FOIDropDown.Value;
            app.conn_metric         =   app.Conn_metricDropDown.Value;
            app.conn_nodes          =   str2double(app.AmountofnodesButtonGroup.SelectedObject.Text);
            app.ROIs                =   app.ROIs;
            app.norm                =   app.norm;
            app.signif              =   app.Significant.Value;
            mode                    =   app.ModeButtonGroup.SelectedObject.Text;

            [app.ba, app.freq, app.ROIs, app.CMfreq, app.CMroi, app.CMavg, app.filename] = load_CM(app.cond, app.conn_metric, app.conn_nodes, app.signif, app.dataset, app.FOIs, app.ROIs, app.norm, app.filename, app.CMavg, app.CMfreq, app.CMroi, app.Smoothening.Value);
            
            app.ba_trans = app.ba(app.mesh2meshmap.current);
        end


        function updateDialogue(app)
            updateConnectoMEG(app);
            if sum(isnan(app.ba_trans))==length(app.ba_trans)
                warndlg('The selected label did not give significant nodes.','Warning');
                app.ROIs = app.ROIs.set_label('M1r');
                updateConnectoMEG(app);
            end
            if or(and(~isempty(app.DialogAppConnectoMeg), any(isvalid(app.DialogAppConnectoMeg))),...
                    strcmp(app.InteractivemodeButton.Enable,"off"))
                % transform_colors(app);
                if any(isvalid(app.DialogAppConnectoMeg))
                    app.DialogAppConnectoMeg.updateplot(app.ba_trans);
                else
                    app.DialogAppConnectoMeg = DialogAppConnectoMeg(app);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %set pathing and other stuff
            [folder, name, ext] = fileparts(which(mfilename));
            cd(folder)
            cd ../
            restoredefaultpath

            addpath(genpath(cd))
            app.outputDir.Value = cd;

            addpath(genpath('C:\Users\Jonathan\Documents\MATLAB\add_on_Matlab\lead'))
            addpath('C:\Users\Jonathan\Documents\MATLAB\add_on_Matlab\spm12')

            %% dependencies
            % root_dependencies = 'C:\Users\Jonathan\Charité - Universitätsmedizin Berlin\Interventional Cognitive Neuromodulation - PROJECT MEG Connectomics\9_Toolboxes';
            % addpath(fullfile(root_dependencies,'spm12_latest\spm12'));
            % addpath(fullfile(root_dependencies,'wjn_toolbox'));
            % addpath(fullfile(root_dependencies,'jvh_toolbox'));
            % addpath('C:\Users\Jonathan\Charité - Universitätsmedizin Berlin\Interventional Cognitive Neuromodulation - PROJECT MEG Connectomics\3_TUTTI\CODE')
            % addpath('C:\Users\Jonathan\Charité - Universitätsmedizin Berlin\Interventional Cognitive Neuromodulation - PROJECT MEG Connectomics\5_Atlas_and_templates')
            % addpath('C:\Users\Jonathan\Charité - Universitätsmedizin Berlin\Interventional Cognitive Neuromodulation - PROJECT MEG Connectomics\3_TUTTI\rawdata_COHSym\')
            % addpath('C:\Users\Jonathan\Documents\MATLAB\add_on_Matlab\spm12')
            % spm('defaults','eeg')
            
            load('mesh2meshmap.mat','mesh2meshmap');
            app.mesh2meshmap = mesh2meshmap;
            app.mesh2meshmap.current = app.mesh2meshmap.asym186_to_20484;
            load('mesh2labels.mat','mesh2labels');
            app.mesh2labels = mesh2labels;
            app.mesh2labels.current = app.mesh2labels.asym186_to_AAL2;
            
            app.AtlasDropDown.Items = app.mesh2labels.Items;

            app.ROIs = ROI();
            
            %app.ROIs = app.ROIs.set_label('M1r');
        end

        % Button pushed function: dirBrowseButton
        function dirBrowseButtonPushed(app, event)
            app.outputDir.Value = uigetdir(pwd,'Select output directory');
        end

        % Value changed function: CohortDropDown
        function CohortDropDownChanged(app, event)
            app.cohort = app.CohortDropDown.Value;
            display(['selected cohort: ' app.cohort])
            if strcmp(app.cohort,'HCP_healthy')
                app.dataset   = 'HCP';
                app.cond = 'healthy';
            elseif strcmp(app.cohort,'OMEGA_healthy')
                app.dataset   = 'OMEGA';
                app.cond = 'healthy';
            elseif strcmp(app.cohort,'OMEGA_PD')
                app.dataset   = 'OMEGA';
                app.cond = 'PD';
            end
            updateDialogue(app)
        end

        % Value changed function: Conn_metricDropDown
        function Conn_metricDropDownChanged(app, event)
            app.conn_metric = app.Conn_metricDropDown.Value;
            display(['selected conn_metric: ' app.conn_metric])
            updateDialogue(app)
        end

        % Value changed function: FOIDropDown
        function FOIDropDownChanged(app, event)
            app.FOIs = app.FOIDropDown.Value;
            display(['selected FOI: ' app.FOIs])
            if strcmp(app.FOIs, 'create_input')
                disp('test')
                app.FOIs = input('define an frequency input as 1x2 matrix [freq_min freq_max], e.g. [4 21 ]\n Minimal frequency is 4 Hz\n Maximal frequency is 37 Hz for Granger causality, else 90 Hz\n FOI = \n');
                assert(isequal(size(app.FOIs),[1 2]),'Input size does not match')
                assert(isa(app.FOIs,'double'),'Input is not a matrix')
                app.FOIDropDown.Value = app.FOIs;
            end
            updateDialogue(app)
        end

        % Selection changed function: AmountofnodesButtonGroup
        function AmountofnodesButtonGroupSelectionChanged(app, event)
            app.conn_nodes          =   str2double(app.AmountofnodesButtonGroup.SelectedObject.Text);
            if app.conn_nodes == 1018
                sym_asym = 'sym';
                app.Significant.Value = 1;
            else
                sym_asym = 'asym';
            end
            if app.conn_nodes == 1028
                app.Significant.Value = 0;
            end
            app.mesh2meshmap.current = app.mesh2meshmap.([sym_asym num2str(app.conn_nodes) '_to_20484']);
            current_atlas=app.AtlasDropDown.Value;
            app.mesh2labels.current = app.mesh2labels.([sym_asym num2str(app.conn_nodes) '_to_' current_atlas]);
            disp('...busy...')
            updateDialogue(app)
            disp('Done!')
        end

        % Value changed function: Significant
        function SignificantValueChanged(app, event)
            app.signif = app.Significant.Value;
            if app.conn_nodes == 1028
                app.Significant.Value =0;
                app.signif =0;
            end
            updateDialogue(app)
        end

        % Value changed function: SpatialNormalization
        function SpatialNormalizationCheck(app, event)
            value = app.SpatialNormalization.Value;
            if value
                app.norm = '_norm';
            else
                app.norm = '';
            end
            if strcmp(app.ROIs.label, 'all')
                updateDialogue(app)
            else
                app.SpatialNormalization.Value = 0;
            end
        end

        % Callback function: ROIButtonGroup, allROIs, defineMNImatrix, 
        % ...and 1 other component
        function ROIButtonGroupSelectionChanged(app, event)
            app.AtlasDropDown.Value='None';
            app.LabelDropDown.Value='None';
            %app.NetworkDropDown.Value='None';
            app.MNIx.Value = [];
            app.MNIy.Value = [];
            app.MNIz.Value = [];
            myROI = event.Source.Text;
            switch myROI
                case 'define MNI matrix'
                    inp = input('define an MNI coordinate as an Ax3 matrix, e.g. [37   -25    64; -37   -25    62]\n MNI = \n');
                    assert(isequal(size(inp,2),[3]),'Input size does not match')
                    assert(isa(inp,'double'),'Input is not a matrix')
                    app.ROIs = app.ROIs.set_coord_interest(inp);
                case 'define mask'
                    [file, pathname] = uigetfile('.nii','Select a nifti file in MNI space');
                    if ~isempty(file)
                        app.ROIs = app.ROIs.set_mask(file, pathname);
                    end
                case 'all ROIs'
                    app.ROIs = app.ROIs.set_label('all');
            end
            disp('selected ROI: ')
            disp(app.ROIs)

            updateDialogue(app)
        end

        % Value changed function: AtlasDropDown
        function AtlasDropDownChanged(app, event)
            %app.NetworkDropDown.Value='None';
            app.LabelDropDown.Value='None';
            current_atlas=app.AtlasDropDown.Value;
            if app.conn_nodes == 1018
                sym_asym = 'sym';
            else
                sym_asym = 'asym';
            end
            app.LabelDropDown.Items = [{'None'}, app.mesh2labels.([sym_asym num2str(app.conn_nodes) '_to_' current_atlas]).myLabel_unique'];
            app.LabelDropDown.ItemsData = [{'None'}, num2cell(app.mesh2labels.([sym_asym num2str(app.conn_nodes) '_to_' current_atlas]).labelnr_unique')];
                        
            % app.NetworkDropDown.Value='None';
            % app.LabelDropDown.Value='None';
            % app.MNIx.Value = [];
            % app.MNIy.Value = [];
            % app.MNIz.Value = [];
            % myROI = app.AtlasDropDown.Value;
            % if ~strcmp(myROI,'None')
            %     app.ROIs = app.ROIs.set_label(myROI);
            %     disp('selected ROI: ')
            %     disp(app.ROIs)
            %     updateDialogue(app)
            % end
            
        end

        % Value changed function: LabelDropDown
        function LabelDropDownChanged(app, event)
            %app.NetworkDropDown.Value='None';
            current_atlas = app.AtlasDropDown.Value;
            app.MNIx.Value = [];
            app.MNIy.Value = [];
            app.MNIz.Value = [];
            %app.ROIButtonGroup.SelectedObject = app.ROIButtonGroup.Buttons(find(strcmp({app.ROIButtonGroup.Buttons.Text},'Atlas')));
            atlas_label_index = app.LabelDropDown.Value;
            if ischar(atlas_label_index)
                atlas_label_index=str2double(atlas_label_index);
            end
            if app.conn_nodes == 1018
                sym_asym = 'sym';
            else
                sym_asym = 'asym';
            end
            app.mesh2labels.current = app.mesh2labels.([sym_asym num2str(app.conn_nodes) '_to_' current_atlas]);
            hit=(app.mesh2labels.current.coorddouble_labelnr==atlas_label_index);
            if any(hit)
                disp('label is found')
                app.ROIs = app.ROIs.set_index(find(hit));
                app.ROIs = app.ROIs.set_label(app.LabelDropDown.Items{app.LabelDropDown.ValueIndex});
                app.ROIs = app.ROIs.determine_ROI();
                updateDialogue(app)
            elseif strcmp(app.LabelDropDown.Value,'None')
                %
            else
                warning('label is not covered by current mesh')
                warndlg('The selected label could not be mapped on any cortical source reconstruction node for this mesh size. (Select another label, atlas or mesh size instead.)','Warning');
                app.ROIs = app.ROIs.set_label('M1r');
                %app.ROIButtonGroup.SelectedObject = app.ROIButtonGroup.Buttons(end);
                updateDialogue(app)
            end
        end

        % Callback function
        function NetworkDropdownChanged(app, event)
            app.LabelDropDown.Value='None';
            app.AtlasDropDown.Value='None';
            app.MNIx.Value = [];
            app.MNIy.Value = [];
            app.MNIz.Value = [];
            mynetwork = app.NetworkDropDown.Value;
            %app.ROIButtonGroup.SelectedObject = app.ROIButtonGroup.Buttons(find(strcmp({app.ROIButtonGroup.Buttons.Text},'Network')));
            if ~strcmp(mynetwork,'None')
                switch mynetwork
                    case 'Emotion regulation'
                        app.ROIs = app.ROIs.set_label('Emotion regulation') ;
                        app.ROIs = app.ROIs.set_coord_interest([-20 -4 -16; 20 -4 -16]);
                    case 'Movement association'
                        app.ROIs = app.ROIs.set_label('Movement association') ;
                        app.ROIs = app.ROIs.set_coord_interest([-4 -8 56; 4 -8 56]);
                    case 'Memory association'
                        app.ROIs = app.ROIs.set_label('Memory association') ;
                        app.ROIs = app.ROIs.set_coord_interest([-34 -60 42; 34 -60 42]);
                    case 'Vision association'
                        app.ROIs = app.ROIs.set_label('Vision association') ;
                        app.ROIs = app.ROIs.set_coord_interest([46 -68 4; -46 -68 4; 26 -66 60; -20 -66 60]);
                    case 'Language network'
                        app.ROIs = app.ROIs.set_label('Language network') ;
                        app.ROIs = app.ROIs.set_coord_interest([-56 -42 4; -56 -8 -6]);
                    %otherwise
                    %app.ROIButtonGroup.SelectedObject = app.ROIButtonGroup.Buttons(end);
                end
                updateDialogue(app)
            end
        end

        % Value changed function: MNIx, MNIy, MNIz
        function MNIValueChanged(app, event)
            if ~isempty(app.MNIx.Value) && ~isempty(app.MNIy.Value) && ~isempty(app.MNIz.Value)
                app.LabelDropDown.Value='None';
                app.AtlasDropDown.Value='None';
                %app.NetworkDropDown.Value='None';
                app.ROIs = app.ROIs.set_coord_interest([app.MNIx.Value app.MNIy.Value app.MNIz.Value]);
                updateDialogue(app)
            end
        end

        % Button pushed function: ShownormativeconnectivityButton
        function ShownormativeconnectivityButtonPushed(app, event)
            updateConnectoMEG(app);
            mode                =   app.ModeButtonGroup.SelectedObject.Text;
            disp(' ... busy ...')
            %[app.ba, app.freq, app.ROIs, app.CM, app.CMavg, app.filename] = load_CM(app.cond, app.conn_metric, app.conn_nodes, app.signif, app.dataset, app.FOIs, app.ROIs, app.norm, app.filename, app.CMavg, app.CM);
            switch mode
                case 'connectogram'
                    if app.conn_nodes==186 && strcmp(app.ROIs.label ,'all')
                        load('mesh2labels_connectogram.mat','mesh2labels');
                        plot_connectogram(app.CMfreq, mesh2labels.asym186_to_custom, 95)
                    else
                        warning('not implemented for large matrices or ROIs other than all')
                    end
                case 'matrix'
                    app.ba=plot_CM(app.cond, app.conn_metric, app.conn_nodes, app.dataset, app.FOIs, app.ROIs, app.norm, mode, app.CMavg, app.CMfreq, app.CMroi);
                case 'spectral plot'
                    app.ba=plot_CM(app.cond, app.conn_metric, app.conn_nodes, app.dataset, app.FOIs, app.ROIs, app.norm, mode, app.CMavg, app.CMfreq, app.CMroi);
                case 'whole-brain connectivity'
                    %[app.CM, app.freq, app.ROI, app.ba]=
                    app.ba=plot_CM(app.cond, app.conn_metric, app.conn_nodes, app.dataset, app.FOIs, app.ROIs, app.norm, mode, app.CMavg, app.CMfreq, app.CMroi);
            end   
            disp('Done!')
            
        end

        % Button pushed function: ShowgeneticcorrelationButton
        function ShowgeneticcorrelationButtonPushed(app, event)
            updateConnectoMEG(app);
            GOI_selected=app.GOIEditField.Value;
            atlas=app.AtlasButtonGroup.SelectedObject.Text;
            
            mode                =   app.ModeButtonGroup.SelectedObject.Text;
            disp(' ... busy ...')

            [app.CMfreq, app.freq]=retrieve_RMap_GOI(app.cond, app.conn_metric, app.conn_nodes, app.dataset, app.FOIs, app.ROIs, app.norm, mode, GOI_selected, atlas, app.CMavg, app.CMfreq);
            disp('Done!')
            
        end

        % Button pushed function: InteractivemodeButton
        function InteractivemodeButtonPushed(app, event)
            app.InteractivemodeButton.Enable = "off";
            updateDialogue(app)            
            disp('Done!')
        end

        % Callback function: GOIEditField, GOIEditField
        function GOIEditFieldValueChanging(app, event)
            [GOI_hit_list_symbol, GOI_hit_list_name] = GOI_search_suggestions(event.Value, app.gene_abbreviations);
            app.GOIListBox.Items=GOI_hit_list_symbol;
        end

        % Value changed function: GOIListBox
        function GOIListBoxChanged(app, event)
            value1 = app.GOIListBox.Value;
           
            persistent chk
            if isempty(chk)
                  chk = 1;
                  pause(0.5); %Add a delay to distinguish single click from a double click
                  if chk == 1
                      %fprintf(1,'\nI am doing a single-click.\n\n');
                      app.GOIEditField.Value = value1;
                      chk = [];
                  end
            %else
            %      chk = [];
            %      fprintf(1,'\nI am doing a double-click.\n\n');
            end
        end

        % Button pushed function: UpdateandrefreshButton
        function UpdateAndRefreshButtonPushed(app, event)
            app.InteractivemodeButton.Enable = "on";
            app.ba_trans_prev=[];
            updateDialogue(app)
        end

        % Button pushed function: LoadintoworkspaceButton
        function LoadIntoWorkspace(app, event)
            assignin('base','CMfreq',app.CMfreq)
            assignin('base','CMroi',app.CMroi)
            assignin('base','freq',app.freq)
            assignin('base','ROIs',app.ROIs)
            assignin('base','ba',app.ba)
            assignin('base','ba_tvals',app.ba_tvals)
            assignin('base','ba_pvals',app.ba_pvals)
            assignin('base','CMavg',app.CMavg)
            assignin('base','filename',app.filename)
            assignin('base','conn_metric',app.conn_metric)
            assignin('base','conn_nodes',app.conn_nodes)
        end

        % Button pushed function: ExportcurrentresultsButton
        function ExportCurrentSettings(app, event)
            ConnectoMEG.CMfreq = app.CMfreq;
            ConnectoMEG.CMroi = app.CMroi;
            ConnectoMEG.freq = app.freq;
            ConnectoMEG.ROI = app.ROIs;
            ConnectoMEG.ba = app.ba;
            ConnectoMEG.CMavg = app.CMavg;
            ConnectoMEG.filename = app.filename;
            ConnectoMEG.conn_metric=app.conn_metric;
            ConnectoMEG.conn_nodes=app.conn_nodes;
            uisave('ConnectoMEG', 'ConnectoMEG.mat' );
            disp(['Data saved!'])
        end

        % Close request function: ConnectoMEGToolboxUIFigure
        function ConnectoMEGToolboxUIFigureCloseRequest(app, event)
            try
                if isvalid(app.DialogAppConnectoMeg)
                     delete(app.DialogAppConnectoMeg)
                end
                if isvalid(app.DialogAppAddAtlas)
                   delete(app.DialogAppAddAtlas)
                end
                delete(app)
            catch
                close all force
            end
        end

        % Button pushed function: ImportAtlasButton
        function ImportAtlasButtonPushed(app, event)
            app.ImportAtlasButton.Enable = "off";
            app.DialogAppAddAtlas = DialogAppAddAtlas(app);
            app.AtlasDropDown.Items = app.mesh2labels.Items;
        end

        % Button pushed function: ShowpercentagedifferenceButton
        function ShowpercentagedifferenceButtonPushed(app, event)
            
            if any(isvalid(app.DialogAppConnectoMegDiff)) && ~isempty(app.ba_trans_prev)
                    app.ba_trans_prev(app.ba_trans_prev==0) = 0.000001;
                    app.ba_trans_perc = (app.ba_trans-app.ba_trans_prev)./app.ba_trans_prev *100;
                    app.DialogAppConnectoMegDiff.showpercentdiff(app.ba_trans_perc);
            else
                app.DialogAppConnectoMegDiff = DialogAppConnectoMeg(app, 'cool');
                
            end
            app.ba_trans_prev=app.ba_trans;
        end

        % Button pushed function: UpdatefromcenterpanelButton
        function UpdatefromcenterpanelButtonPushed(app, event)
            app.CohortDropDown_2.Value = app.CohortDropDown.Value;
            app.AtlasDropDown_2.Value = app.AtlasDropDown.Value;
            app.FOIDropDown_2.Value   = app.FOIDropDown.Value;
            app.Conn_metricDropDown_2.Value = app.Conn_metricDropDown.Value;
            app.LabelDropDown_2.Items = app.LabelDropDown.Items;
            app.LabelDropDown_2.ItemsData = app.LabelDropDown.ItemsData;
            app.LabelDropDown_2.Value = app.LabelDropDown.Value;
        end

        % Button pushed function: ShowwithincohortspectraltmapButton
        function ShowwithincohortspectraltmapButtonPushed(app, event)
            
            if isequal(app.FOIDropDown_2.Value, app.FOIDropDown.Value)
                app.FOIDropDown_2.Enable= "on";
            else
                FOIs = app.FOIDropDown_2.Value;
                app.FOIDropDown_2.Enable = "off";
                
                updateConnectoMEG(app)
                [ba, freq, ROIs, CMfreq, CMroi, CMavg, filename] = load_CM(app.cond, app.conn_metric, app.conn_nodes, app.signif, app.dataset, FOIs, app.ROIs, app.norm, app.filename, app.CMavg, app.CMfreq, app.CMroi);
              
                app.ba_tvals = NaN(size(ba,1),1);
                app.ba_tvals = NaN(size(ba,1),1);
                %%%%%%%%%%%
                load(app.filename,'subjectlist')
                    N1=length(subjectlist);
                    load(filename,'subjectlist')
                    N2=length(subjectlist);
                    
                load(['sd' char(app.filename)],'CMsd')
                %CMsd1_to_sum=CMsd(:,:,freq.index);
                %CMsd1_to_sum=CMsd1_to_sum(:,ROIs.index,:);
                SD1=jvh_slice_sd_3matrix(CMsd, ROIs.index, freq.index);
                load(['sd' char(filename)],'CMsd')
                %CMsd2_to_sum=CMsd(:,:,freq.index);
                %CMsd2_to_sum=CMsd2_to_sum(:,ROIs.index,:);
                SD2=jvh_slice_sd_3matrix(CMsd, ROIs.index, freq.index);
                
                [app.ba_tvals, app.ba_pvals] =jvh_unpaired_ttest_pooled_sd(app.ba,ba,SD1,SD2,N1,N2);
                
                %%%%%%%%%%%
                %for i=1:app.conn_nodes
                %    [~,app.ba_pvals(i),~,stats]=ttest(CMroi(i,:), app.CMroi(i,:),"Alpha",0.05,"Tail","both");
                %    app.ba_tvals(i)=stats.tstat;
                %end
                % no bonferonni correction
                app.ba_pvals = app.ba_pvals; % * app.conn_nodes
                app.ba_tvals(or(isnan(ba),isnan(app.ba)))=NaN;
                app.ba_pvals(or(isnan(ba),isnan(app.ba)))=NaN;
                app.ba_tvals_trans = app.ba_tvals(app.mesh2meshmap.current);
                app.ba_pvals_trans = app.ba_pvals(app.mesh2meshmap.current);
                
                if any(isvalid(app.DialogAppConnectoMegDiff))
                        app.DialogAppConnectoMegDiff.showtmap(app.ba_tvals_trans);
                else
                    app.DialogAppConnectoMegDiff = DialogAppConnectoMeg(app, 'redblue');
                end
            end 
        end

        % Button pushed function: ShowwithincohortspatialtmapButton
        function ShowwithincohortspatialtmapButtonPushed(app, event)
            
            if isequal(app.LabelDropDown_2.Value, app.LabelDropDown.Value)
                app.LabelDropDown_2.Enable = "on";
            else
                atlas_label_index = app.LabelDropDown_2.Value;
                app.LabelDropDown_2.Enable = "off";
                updateConnectoMEG(app)
                %%% change update label dropdown changed
                if ischar(atlas_label_index)
                    atlas_label_index=str2double(atlas_label_index);
                end
                hit=(app.mesh2labels.current.coorddouble_labelnr==atlas_label_index);
                if any(hit)
                    disp('label is found')
                    ROIs = app.ROIs.set_index(find(hit));
                    ROIs = ROIs.set_label(app.LabelDropDown_2.Items{app.LabelDropDown_2.ValueIndex});
                    ROIs = ROIs.determine_ROI();
                else
                    warning('label is not covered by current mesh')
                    warndlg('The selected label could not be mapped on any cortical source reconstruction node for this mesh size. (Select another label, atlas or mesh size instead.)','Warning');    
                end
                
                if any(hit)
                 
                   [ba, freq, ROIs, CMfreq, CMroi, CMavg, filename] = load_CM(app.cond, app.conn_metric, app.conn_nodes, app.signif, app.dataset, app.FOIs, ROIs, app.norm, app.filename, app.CMavg, app.CMfreq, app.CMroi);
                
                    app.ba_tvals = NaN(size(ba,1),1);
                    app.ba_pvals = NaN(size(ba,1),1);

                    %%%%%%%%%%%
                    load(app.filename,'subjectlist')
                    N1=length(subjectlist);
                    load(filename,'subjectlist')
                    N2=length(subjectlist);

                    load(['sd' char(app.filename)],'CMsd')
                    %CMsd1_to_sum=CMsd(:,:,freq.index);
                    %CMsd1_to_sum=CMsd1_to_sum(:,ROIs.index,:);
                    SD1=jvh_slice_sd_3matrix(CMsd, ROIs.index, freq.index);
                    load(['sd' char(filename)],'CMsd')
                    %CMsd2_to_sum=CMsd(:,:,freq.index);
                    %CMsd2_to_sum=CMsd2_to_sum(:,ROIs.index,:);
                    SD2=jvh_slice_sd_3matrix(CMsd, ROIs.index, freq.index);
                    
                    [app.ba_tvals, app.ba_pvals] =jvh_unpaired_ttest_pooled_sd(app.ba,ba,SD1,SD2,N1,N2);
                    
                    %%%%%%%%%%%

                    % for i=1:app.conn_nodes
                    %     [~,app.ba_pvals(i),~,stats]=ttest(CMfreq(i,:), app.CMfreq(i,:),"Alpha",0.05,"Tail","both");
                    %     app.ba_tvals(i)=stats.tstat;
                    % end
                    % no bonferonni correction
                    app.ba_pvals = app.ba_pvals; % * app.conn_nodes;
                    app.ba_tvals(or(isnan(ba),isnan(app.ba)))=NaN;
                    app.ba_pvals(or(isnan(ba),isnan(app.ba)))=NaN;
                    app.ba_tvals_trans = app.ba_tvals(app.mesh2meshmap.current);
                    app.ba_pvals_trans = app.ba_pvals(app.mesh2meshmap.current);
                    
                    if any(isvalid(app.DialogAppConnectoMegDiff))
                            app.DialogAppConnectoMegDiff.showtmap(app.ba_tvals_trans);
                    else
                        app.DialogAppConnectoMegDiff = DialogAppConnectoMeg(app, 'redblue');
                    end
                end
            end 
        end

        % Button pushed function: ShowacrosscohorttmapButton
        function ShowacrosscohorttmapButtonPushed(app, event)
            if isequal(app.CohortDropDown_2.Value, app.CohortDropDown.Value)
                app.CohortDropDown_2.Enable = "on";
            else
                cohort = app.CohortDropDown_2.Value;
                app.CohortDropDown_2.Enable = "off";
                updateConnectoMEG(app)
                %%% change update label dropdown changed
                if strcmp(cohort,'HCP_healthy')
                    dataset   = 'HCP';
                    cond = 'healthy';
                elseif strcmp(cohort,'OMEGA_healthy')
                    dataset   = 'OMEGA';
                    cond = 'healthy';
                elseif strcmp(cohort,'OMEGA_PD')
                    dataset   = 'OMEGA';
                    cond = 'PD';
                end
                updateDialogue(app)
                 
                   [ba, freq, ROIs, CMfreq, CMroi, CMavg, filename] = load_CM(cond, app.conn_metric, app.conn_nodes, app.signif, dataset, app.FOIs, app.ROIs, app.norm, app.filename, app.CMavg, app.CMfreq, app.CMroi);
                
                    app.ba_tvals = NaN(size(ba,1),1);
                    app.ba_pvals = NaN(size(ba,1),1);
                    
                    load(app.filename,'subjectlist')
                    N1=length(subjectlist);
                    load(filename,'subjectlist')
                    N2=length(subjectlist);
                    
                    load(['sd' char(app.filename)],'CMsd')
                    %CMsd1_to_sum=CMsd(:,:,freq.index);
                    %CMsd1_to_sum=CMsd1_to_sum(:,ROIs.index,:);
                    SD1=jvh_slice_sd_3matrix(CMsd, ROIs.index, freq.index);
                    load(['sd' char(filename)],'CMsd')
                    %CMsd2_to_sum=CMsd(:,:,freq.index);
                    %CMsd2_to_sum=CMsd2_to_sum(:,ROIs.index,:);
                    SD2=jvh_slice_sd_3matrix(CMsd, ROIs.index, freq.index);
                    
                    [app.ba_tvals, app.ba_pvals] =jvh_unpaired_ttest_pooled_sd(app.ba,ba,SD1,SD2,N1,N2);
                    % no bonferonni correction
                    app.ba_pvals = app.ba_pvals;% * app.conn_nodes;
                    app.ba_tvals(or(isnan(ba),isnan(app.ba)))=NaN;
                    app.ba_pvals(or(isnan(ba),isnan(app.ba)))=NaN;
                    app.ba_tvals_trans = app.ba_tvals(app.mesh2meshmap.current);
                    app.ba_pvals_trans = app.ba_pvals(app.mesh2meshmap.current);
                    
                    if any(isvalid(app.DialogAppConnectoMegDiff))
                            app.DialogAppConnectoMegDiff.showtmap(app.ba_tvals_trans);
                    else
                        app.DialogAppConnectoMegDiff = DialogAppConnectoMeg(app, 'redblue');
                    end
                end
        
        end

        % Button pushed function: UpdatetmaptopmapButton
        function UpdatetmaptopmapButtonPushed(app, event)
            if any(isvalid(app.DialogAppConnectoMegDiff))
                            app.DialogAppConnectoMegDiff.showpmap(app.ba_pvals_trans);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ConnectoMEGToolboxUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {633, 633, 633};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {633, 633};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x', 214};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConnectoMEGToolboxUIFigure = uifigure('Visible', 'off');
            app.ConnectoMEGToolboxUIFigure.Name = 'ConnectoMEG Toolbox';
            app.ConnectoMEGToolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConnectoMEGToolboxUIFigureCloseRequest, true);
            app.ConnectoMEGToolboxUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.PreferencesMenu = uimenu(app.ConnectoMEGToolboxUIFigure);
            app.PreferencesMenu.Text = 'Preferences';
            app.ToolsMenu = uimenu(app.ConnectoMEGToolboxUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.OpenGeneticsMenu = uimenu(app.ToolsMenu);
            app.OpenGeneticsMenu.Text = 'Open Genetics';
            app.GridLayout = uigridlayout(app.ConnectoMEGToolboxUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.basicTab = uitab(app.TabGroup);
            app.basicTab.Title = 'basic';
            app.geneticanalysisTab = uitab(app.TabGroup);
            app.geneticanalysisTab.Title = 'genetic analysis';
            app.AtlasButtonGroup = uibuttongroup(app.geneticanalysisTab);
            app.AtlasButtonGroup.Title = 'Atlas';
            app.NoneButton = uiradiobutton(app.AtlasButtonGroup);
            app.NoneButton.Text = 'None';
            app.DesikanKillianyButton = uiradiobutton(app.AtlasButtonGroup);
            app.DesikanKillianyButton.Text = 'Desikan-Killiany';
            app.DesikanKillianyButton.Value = true;
            app.ShowgeneticcorrelationButton = uibutton(app.geneticanalysisTab, 'push');
            app.ShowgeneticcorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowgeneticcorrelationButtonPushed, true);
            app.ShowgeneticcorrelationButton.Text = 'Show genetic correlation';
            app.GOIEditFieldLabel = uilabel(app.geneticanalysisTab);
            app.GOIEditFieldLabel.Text = 'GOI';
            app.GOIEditField = uieditfield(app.geneticanalysisTab, 'text');
            app.GOIEditField.ValueChangedFcn = createCallbackFcn(app, @GOIEditFieldValueChanging, true);
            app.GOIEditField.ValueChangingFcn = createCallbackFcn(app, @GOIEditFieldValueChanging, true);
            app.GOIselectedLabel = uilabel(app.geneticanalysisTab);
            app.GOIselectedLabel.Text = 'GOI selected';
            app.GOIListBox = uilistbox(app.geneticanalysisTab);
            app.GOIListBox.Items = {'GOI'};
            app.GOIListBox.ValueChangedFcn = createCallbackFcn(app, @GOIListBoxChanged, true);
            app.GOIListBox.Value = 'GOI';
            app.ComparedifferencesTab = uitab(app.TabGroup);
            app.ComparedifferencesTab.Title = 'Compare differences';
            app.UpdatetmaptopmapButton = uibutton(app.ComparedifferencesTab, 'push');
            app.UpdatetmaptopmapButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetmaptopmapButtonPushed, true);
            app.UpdatetmaptopmapButton.Text = 'Update t-map to p-map';
            app.ShowacrosscohorttmapButton = uibutton(app.ComparedifferencesTab, 'push');
            app.ShowacrosscohorttmapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowacrosscohorttmapButtonPushed, true);
            app.ShowacrosscohorttmapButton.Text = 'Show across cohort t-map';
            app.ShowwithincohortspectraltmapButton = uibutton(app.ComparedifferencesTab, 'push');
            app.ShowwithincohortspectraltmapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowwithincohortspectraltmapButtonPushed, true);
            app.ShowwithincohortspectraltmapButton.Text = 'Show within-cohort spectral t-map';
            app.ShowpercentagedifferenceButton = uibutton(app.ComparedifferencesTab, 'push');
            app.ShowpercentagedifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpercentagedifferenceButtonPushed, true);
            app.ShowpercentagedifferenceButton.Text = 'Show percentage difference';
            app.ShowwithincohortspatialtmapButton = uibutton(app.ComparedifferencesTab, 'push');
            app.ShowwithincohortspatialtmapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowwithincohortspatialtmapButtonPushed, true);
            app.ShowwithincohortspatialtmapButton.Text = 'Show within-cohort spatial t-map';
            app.LabelDropDown_2 = uidropdown(app.ComparedifferencesTab);
            app.LabelDropDown_2.Items = {'None', 'Precentral_L', 'Precentral_R', 'Frontal_Sup_2_L', 'Frontal_Sup_2_R', 'Frontal_Mid_2_L', 'Frontal_Mid_2_R', 'Frontal_Inf_Oper_L', 'Frontal_Inf_Oper_R', 'Frontal_Inf_Tri_L', 'Frontal_Inf_Tri_R', 'Frontal_Inf_Orb_2_L', 'Frontal_Inf_Orb_2_R', 'Rolandic_Oper_L', 'Rolandic_Oper_R', 'Supp_Motor_Area_L', 'Supp_Motor_Area_R', 'Olfactory_L', 'Olfactory_R', 'Frontal_Sup_Medial_L', 'Frontal_Sup_Medial_R', 'Frontal_Med_Orb_L', 'Frontal_Med_Orb_R', 'Rectus_L', 'Rectus_R', 'OFCmed_L', 'OFCmed_R', 'OFCant_L', 'OFCant_R', 'OFCpost_L', 'OFCpost_R', 'OFClat_L', 'OFClat_R', 'Insula_L', 'Insula_R', 'Cingulate_Ant_L', 'Cingulate_Ant_R', 'Cingulate_Mid_L', 'Cingulate_Mid_R', 'Cingulate_Post_L', 'Cingulate_Post_R', 'Hippocampus_L', 'Hippocampus_R', 'ParaHippocampal_L', 'ParaHippocampal_R', 'Amygdala_L', 'Amygdala_R', 'Calcarine_L', 'Calcarine_R', 'Cuneus_L', 'Cuneus_R', 'Lingual_L', 'Lingual_R', 'Occipital_Sup_L', 'Occipital_Sup_R', 'Occipital_Mid_L', 'Occipital_Mid_R', 'Occipital_Inf_L', 'Occipital_Inf_R', 'Fusiform_L', 'Fusiform_R', 'Postcentral_L', 'Postcentral_R', 'Parietal_Sup_L', 'Parietal_Sup_R', 'Parietal_Inf_L', 'Parietal_Inf_R', 'SupraMarginal_L', 'SupraMarginal_R', 'Angular_L', 'Angular_R', 'Precuneus_L', 'Precuneus_R', 'Paracentral_Lobule_L', 'Paracentral_Lobule_R', 'Caudate_L', 'Caudate_R', 'Putamen_L', 'Putamen_R', 'Pallidum_L', 'Pallidum_R', 'Thalamus_L', 'Thalamus_R', 'Temporal_Sup_L', 'Temporal_Sup_R', 'Temporal_Pole_Sup_L', 'Temporal_Pole_Sup_R', 'Temporal_Mid_L', 'Temporal_Mid_R', 'Temporal_Pole_Mid_L', 'Temporal_Pole_Mid_R', 'Temporal_Inf_L', 'Temporal_Inf_R'};
            app.LabelDropDown_2.ItemsData = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94'};
            app.LabelDropDown_2.Enable = 'off';
            app.LabelDropDown_2.Value = 'None';
            app.AtlasDropDown_2 = uidropdown(app.ComparedifferencesTab);
            app.AtlasDropDown_2.Items = {'None', 'Desikan', 'AAL2', 'ConnectoMEGROI', 'Brainnectome', 'Mindboggle', 'Yeo7', 'Yeo17'};
            app.AtlasDropDown_2.Enable = 'off';
            app.AtlasDropDown_2.Value = 'AAL2';
            app.FOIDropDown_2 = uidropdown(app.ComparedifferencesTab);
            app.FOIDropDown_2.Items = {'θ (4-7 Hz)', 'α (7-12 Hz)', 'ß low (13-21 Hz)', 'ß high (21-35 Hz)', 'ß total (13-35 Hz)', 'γ broad (60-90 Hz)', 'all available frequencies', 'own input frequencies (freq_min freq_max)'};
            app.FOIDropDown_2.ItemsData = {'theta', 'alpha', 'beta low', 'beta high', 'beta total', 'gamma broad', 'all', 'create_input'};
            app.FOIDropDown_2.Enable = 'off';
            app.FOIDropDown_2.Value = 'theta';
            app.Conn_metricDropDown_2 = uidropdown(app.ComparedifferencesTab);
            app.Conn_metricDropDown_2.Items = {'coherence', 'imaginary part of coherence', 'time-reserved corrected Granger causality (only with 186 nodes)'};
            app.Conn_metricDropDown_2.ItemsData = {'coh', 'icoh', 'grang'};
            app.Conn_metricDropDown_2.Enable = 'off';
            app.Conn_metricDropDown_2.Value = 'coh';
            app.CohortDropDown_2 = uidropdown(app.ComparedifferencesTab);
            app.CohortDropDown_2.Items = {'Human Connectome Project (healthy)', 'OMEGA (healthy)', 'OMEGA (Parkinson''s disease)'};
            app.CohortDropDown_2.ItemsData = {'HCP_healthy', 'OMEGA_healthy', 'OMEGA_PD'};
            app.CohortDropDown_2.Enable = 'off';
            app.CohortDropDown_2.Value = 'HCP_healthy';
            app.UpdatefromcenterpanelButton = uibutton(app.ComparedifferencesTab, 'push');
            app.UpdatefromcenterpanelButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefromcenterpanelButtonPushed, true);
            app.UpdatefromcenterpanelButton.Text = 'Update from center panel';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ConnectivitymetricDropDownLabel = uilabel(app.CenterPanel);
            app.ConnectivitymetricDropDownLabel.Text = 'Connectivity metric';
            app.FrequencybandofinterestLabel = uilabel(app.CenterPanel);
            app.FrequencybandofinterestLabel.Text = {'Frequency band'; 'of interest'};
            app.CohortDropDownLabel = uilabel(app.CenterPanel);
            app.CohortDropDownLabel.Text = 'Cohort';
            app.ShownormativeconnectivityButton = uibutton(app.CenterPanel, 'push');
            app.ShownormativeconnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @ShownormativeconnectivityButtonPushed, true);
            app.ShownormativeconnectivityButton.Text = 'Show normative connectivity';
            app.ROIButtonGroup = uibuttongroup(app.CenterPanel);
            app.ROIButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIButtonGroupSelectionChanged, true);
            app.ROIButtonGroup.Title = 'Regions of interests: MNI coordinate';
            app.ROIsLabel = uilabel(app.ROIButtonGroup);
            app.ROIsLabel.Text = 'Atlas';
            app.MNIxyzEditFieldLabel = uilabel(app.ROIButtonGroup);
            app.MNIxyzEditFieldLabel.Text = 'MNI (x,y,z)';
            app.AtlasLabel = uilabel(app.ROIButtonGroup);
            app.MNIz = uieditfield(app.ROIButtonGroup, 'numeric');
            app.MNIz.Limits = [-90 90];
            app.MNIz.AllowEmpty = 'on';
            app.MNIz.ValueChangedFcn = createCallbackFcn(app, @MNIValueChanged, true);
            app.MNIz.Placeholder = 'custom';
            app.MNIz.Value = [];
            app.MNIy = uieditfield(app.ROIButtonGroup, 'numeric');
            app.MNIy.Limits = [-120 120];
            app.MNIy.AllowEmpty = 'on';
            app.MNIy.ValueChangedFcn = createCallbackFcn(app, @MNIValueChanged, true);
            app.MNIy.Placeholder = 'custom';
            app.MNIy.Value = [];
            app.MNIx = uieditfield(app.ROIButtonGroup, 'numeric');
            app.MNIx.Limits = [-90 90];
            app.MNIx.AllowEmpty = 'on';
            app.MNIx.ValueChangedFcn = createCallbackFcn(app, @MNIValueChanged, true);
            app.MNIx.Placeholder = 'custom';
            app.MNIx.Value = [];
            app.LabelDropDown = uidropdown(app.ROIButtonGroup);
            app.LabelDropDown.Items = {'None', 'Precentral_L', 'Precentral_R', 'Frontal_Sup_2_L', 'Frontal_Sup_2_R', 'Frontal_Mid_2_L', 'Frontal_Mid_2_R', 'Frontal_Inf_Oper_L', 'Frontal_Inf_Oper_R', 'Frontal_Inf_Tri_L', 'Frontal_Inf_Tri_R', 'Frontal_Inf_Orb_2_L', 'Frontal_Inf_Orb_2_R', 'Rolandic_Oper_L', 'Rolandic_Oper_R', 'Supp_Motor_Area_L', 'Supp_Motor_Area_R', 'Olfactory_L', 'Olfactory_R', 'Frontal_Sup_Medial_L', 'Frontal_Sup_Medial_R', 'Frontal_Med_Orb_L', 'Frontal_Med_Orb_R', 'Rectus_L', 'Rectus_R', 'OFCmed_L', 'OFCmed_R', 'OFCant_L', 'OFCant_R', 'OFCpost_L', 'OFCpost_R', 'OFClat_L', 'OFClat_R', 'Insula_L', 'Insula_R', 'Cingulate_Ant_L', 'Cingulate_Ant_R', 'Cingulate_Mid_L', 'Cingulate_Mid_R', 'Cingulate_Post_L', 'Cingulate_Post_R', 'Hippocampus_L', 'Hippocampus_R', 'ParaHippocampal_L', 'ParaHippocampal_R', 'Amygdala_L', 'Amygdala_R', 'Calcarine_L', 'Calcarine_R', 'Cuneus_L', 'Cuneus_R', 'Lingual_L', 'Lingual_R', 'Occipital_Sup_L', 'Occipital_Sup_R', 'Occipital_Mid_L', 'Occipital_Mid_R', 'Occipital_Inf_L', 'Occipital_Inf_R', 'Fusiform_L', 'Fusiform_R', 'Postcentral_L', 'Postcentral_R', 'Parietal_Sup_L', 'Parietal_Sup_R', 'Parietal_Inf_L', 'Parietal_Inf_R', 'SupraMarginal_L', 'SupraMarginal_R', 'Angular_L', 'Angular_R', 'Precuneus_L', 'Precuneus_R', 'Paracentral_Lobule_L', 'Paracentral_Lobule_R', 'Caudate_L', 'Caudate_R', 'Putamen_L', 'Putamen_R', 'Pallidum_L', 'Pallidum_R', 'Thalamus_L', 'Thalamus_R', 'Temporal_Sup_L', 'Temporal_Sup_R', 'Temporal_Pole_Sup_L', 'Temporal_Pole_Sup_R', 'Temporal_Mid_L', 'Temporal_Mid_R', 'Temporal_Pole_Mid_L', 'Temporal_Pole_Mid_R', 'Temporal_Inf_L', 'Temporal_Inf_R'};
            app.LabelDropDown.ItemsData = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94'};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelDropDownChanged, true);
            app.LabelDropDown.Value = 'None';
            app.AtlasDropDown = uidropdown(app.ROIButtonGroup);
            app.AtlasDropDown.Items = {'Desikan', 'AAL2', 'ConnectoMEGROI', 'Brainnectome', 'Mindboggle', 'Yeo7', 'Yeo17'};
            app.AtlasDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasDropDownChanged, true);
            app.AtlasDropDown.Value = 'AAL2';
            app.definemask = uibutton(app.ROIButtonGroup, 'push');
            app.definemask.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonGroupSelectionChanged, true);
            app.definemask.Text = 'define mask';
            app.defineMNImatrix = uibutton(app.ROIButtonGroup, 'push');
            app.defineMNImatrix.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonGroupSelectionChanged, true);
            app.defineMNImatrix.Text = 'define MNI matrix';
            app.allROIs = uibutton(app.ROIButtonGroup, 'push');
            app.allROIs.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonGroupSelectionChanged, true);
            app.allROIs.Text = 'all ROIs';
            app.ImportAtlasButton = uibutton(app.ROIButtonGroup, 'push');
            app.ImportAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAtlasButtonPushed, true);
            app.ImportAtlasButton.Text = 'import new atlas';
            app.InteractivemodeButton = uibutton(app.CenterPanel, 'push');
            app.InteractivemodeButton.ButtonPushedFcn = createCallbackFcn(app, @InteractivemodeButtonPushed, true);
            app.InteractivemodeButton.Text = 'Interactive mode';
            app.FOIDropDown = uidropdown(app.CenterPanel);
            app.FOIDropDown.Items = {'θ (4-7 Hz)', 'α (7-12 Hz)', 'ß low (13-21 Hz)', 'ß high (21-35 Hz)', 'ß total (13-35 Hz)', 'γ broad (60-90 Hz)', 'all available frequencies', 'own input frequencies (freq_min freq_max)'};
            app.FOIDropDown.ItemsData = {'theta', 'alpha', 'beta low', 'beta high', 'beta total', 'gamma broad', 'all', 'create_input'};
            app.FOIDropDown.ValueChangedFcn = createCallbackFcn(app, @FOIDropDownChanged, true);
            app.FOIDropDown.Value = 'theta';
            app.Conn_metricDropDown = uidropdown(app.CenterPanel);
            app.Conn_metricDropDown.Items = {'coherence', 'imaginary part of coherence', 'time-reserved corrected Granger causality (only with 186 nodes)'};
            app.Conn_metricDropDown.ItemsData = {'coh', 'icoh', 'grang'};
            app.Conn_metricDropDown.ValueChangedFcn = createCallbackFcn(app, @Conn_metricDropDownChanged, true);
            app.Conn_metricDropDown.Value = 'coh';
            app.CohortDropDown = uidropdown(app.CenterPanel);
            app.CohortDropDown.Items = {'Human Connectome Project (healthy)', 'OMEGA (healthy)', 'OMEGA (Parkinson''s disease)'};
            app.CohortDropDown.ItemsData = {'HCP_healthy', 'OMEGA_healthy', 'OMEGA_PD'};
            app.CohortDropDown.ValueChangedFcn = createCallbackFcn(app, @CohortDropDownChanged, true);
            app.CohortDropDown.Value = 'HCP_healthy';
            app.ToolboxLabel = uilabel(app.CenterPanel);
            app.ToolboxLabel.Text = 'ConnectoMEG Toolbox: for oscillatory normative connectomes ';
            app.RightPanel = uipanel(app.GridLayout);
            app.SpatialNormalization = uicheckbox(app.RightPanel);
            app.SpatialNormalization.ValueChangedFcn = createCallbackFcn(app, @SpatialNormalizationCheck, true);
            app.SpatialNormalization.Text = {'Spatial normalization'; '(only when ROIs = ''all'')'};
            app.Significant = uicheckbox(app.RightPanel);
            app.Significant.ValueChangedFcn = createCallbackFcn(app, @SignificantValueChanged, true);
            app.Significant.Text = 'Restrict to significant nodes';
            app.Significant.Value = true;
            app.ModeButtonGroup = uibuttongroup(app.RightPanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.wholebrainconnectivityButton = uiradiobutton(app.ModeButtonGroup);
            app.wholebrainconnectivityButton.Text = 'whole-brain connectivity';
            app.wholebrainconnectivityButton.Value = true;
            app.spectralplotButton = uiradiobutton(app.ModeButtonGroup);
            app.spectralplotButton.Text = 'spectral plot';
            app.matrixButton = uiradiobutton(app.ModeButtonGroup);
            app.matrixButton.Text = 'matrix';
            app.connectogramButton = uiradiobutton(app.ModeButtonGroup);
            app.connectogramButton.Text = 'connectogram';
            app.PlotsettingsLabel = uilabel(app.RightPanel);
            app.PlotsettingsLabel.Text = 'Plot settings';
            app.AmountofnodesButtonGroup = uibuttongroup(app.RightPanel);
            app.AmountofnodesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AmountofnodesButtonGroupSelectionChanged, true);
            app.AmountofnodesButtonGroup.Title = 'Amount of nodes';
            app.Button_186 = uiradiobutton(app.AmountofnodesButtonGroup);
            app.Button_186.Text = '186';
            app.Button_186.Value = true;
            app.Button_1028 = uiradiobutton(app.AmountofnodesButtonGroup);
            app.Button_1028.Text = '1028';
            app.Button_1018_shuffled = uiradiobutton(app.AmountofnodesButtonGroup);
            app.Button_1018_shuffled.Text = '1018';
            app.symmetricalmeshshuffledLabel = uilabel(app.AmountofnodesButtonGroup);
            app.symmetricalmeshshuffledLabel.Text = {'symmetrical mesh'; '+ shuffled'};
            app.UpdateandrefreshButton = uibutton(app.RightPanel, 'push');
            app.UpdateandrefreshButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAndRefreshButtonPushed, true);
            app.UpdateandrefreshButton.Text = 'Update and refresh ';
            app.ExportcurrentresultsButton = uibutton(app.RightPanel, 'push');
            app.ExportcurrentresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCurrentSettings, true);
            app.ExportcurrentresultsButton.Text = 'Export current results';
            app.LoadintoworkspaceButton = uibutton(app.RightPanel, 'push');
            app.LoadintoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntoWorkspace, true);
            app.LoadintoworkspaceButton.Text = 'Load into workspace';
            app.Smoothening = uicheckbox(app.RightPanel);
            app.Smoothening.Text = 'Spatial smoothening (20mm)';
            app.Smoothening.Value = true;
            app.dirBrowseButton = uibutton(app.ConnectoMEGToolboxUIFigure, 'push');
            app.dirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @dirBrowseButtonPushed, true);
            app.dirBrowseButton.Text = 'Browse';
            app.outputdirectoryLabel = uilabel(app.ConnectoMEGToolboxUIFigure);
            app.outputdirectoryLabel.Text = 'output directory';
            app.outputDir = uieditfield(app.ConnectoMEGToolboxUIFigure, 'text');
            % Show the figure after all components are created
            app.ConnectoMEGToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectoMEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConnectoMEGToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConnectoMEGToolboxUIFigure)
        end
    end
end