classdef DialogAppAddAtlas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddAtlasUIFigure              matlab.ui.Figure
        atlas_selected_nifti          matlab.ui.control.EditField
        atlas_selected_txt            matlab.ui.control.EditField
        parcellationnumberstoexcludeEditField  matlab.ui.control.EditField
        parcellationnumberstoexcludeEditFieldLabel  matlab.ui.control.Label
        closeButton                   matlab.ui.control.Button
        computeatlasmappingButton     matlab.ui.control.Button
        shortatlasnameEditField       matlab.ui.control.EditField
        shortatlasnameEditFieldLabel  matlab.ui.control.Label
        selectatlasniftiButton        matlab.ui.control.Button
        selectatlastxtButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        atlas_name
        atlas_txt % Description
        atlas_nifti
        atlas_exclusion
        MainApp
    end
    
    methods (Access = private)
        
        function update_atlas(app)
            exclude_areas =  eval(app.parcellationnumberstoexcludeEditField.Value);
            app.atlas_name = app.shortatlasnameEditField.Value;
            app.atlas_name = erase(app.atlas_name," ");
            if ~app.atlas_name
                app.atlas_name = 'custom';
            end
            %app.MainApp.AtlasDropDown.Items(end+1) = {app.atlas_name};
            app.MainApp.mesh2labels = add_atlas_to_mesh2labels(app.atlas_txt, app.atlas_nifti, app.atlas_name, exclude_areas);
            
            msgbox("Atlas mapping computed completed. It is now available among the labels.");
            if isvalid(app.MainApp)
                app.MainApp.ImportAtlasButton.Enable = "on";
            end
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Callback function: AddAtlasUIFigure, closeButton
        function UIFigureCloseRequest(app, event)
            try
                if isvalid(app.MainApp)
                    app.MainApp.ImportAtlasButton.Enable = "on";
                end
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: selectatlastxtButton
        function selectatlastxtButtonPushed(app, event)
            [app.atlas_txt,location] = uigetfile("*.txt","Select an atlas text file (space separated)","MultiSelect","off")
            if app.atlas_txt
                app.atlas_selected_txt.Value = app.atlas_txt;
                addpath(location)
            end
        end

        % Button pushed function: selectatlasniftiButton
        function selectatlasniftiButtonPushed(app, event)
            [app.atlas_nifti,location] = uigetfile({"*.nii;*.nii.gz", "nifti file (*.nii, *.nii.gz)"}, "Select an atlas nifti file","MultiSelect","off")
            if app.atlas_nifti
                app.atlas_selected_nifti.Value = app.atlas_nifti;
                addpath(location)
            end
        end

        % Button pushed function: computeatlasmappingButton
        function computeatlasmappingButtonPushed(app, event)
            %app.computeatlasmappingButton.Enable = 'off';
            update_atlas(app);
            app.computeatlasmappingButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddAtlasUIFigure = uifigure('Visible', 'off');
            app.AddAtlasUIFigure.Name = 'Add Atlas';
            app.AddAtlasUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.selectatlastxtButton = uibutton(app.AddAtlasUIFigure, 'push');
            app.selectatlastxtButton.ButtonPushedFcn = createCallbackFcn(app, @selectatlastxtButtonPushed, true);
            app.selectatlastxtButton.Text = 'select atlas txt';
            app.selectatlasniftiButton = uibutton(app.AddAtlasUIFigure, 'push');
            app.selectatlasniftiButton.ButtonPushedFcn = createCallbackFcn(app, @selectatlasniftiButtonPushed, true);
            app.selectatlasniftiButton.Text = 'select atlas nifti';
            app.shortatlasnameEditFieldLabel = uilabel(app.AddAtlasUIFigure);
            app.shortatlasnameEditFieldLabel.Text = 'short atlas name';
            app.shortatlasnameEditField = uieditfield(app.AddAtlasUIFigure, 'text');
            app.shortatlasnameEditField.CharacterLimits = [0 12];
            app.shortatlasnameEditField.Value = 'custom';
            app.computeatlasmappingButton = uibutton(app.AddAtlasUIFigure, 'push');
            app.computeatlasmappingButton.ButtonPushedFcn = createCallbackFcn(app, @computeatlasmappingButtonPushed, true);
            app.computeatlasmappingButton.Text = 'compute atlas mapping';
            app.closeButton = uibutton(app.AddAtlasUIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.closeButton.Text = 'close';
            app.parcellationnumberstoexcludeEditFieldLabel = uilabel(app.AddAtlasUIFigure);
            app.parcellationnumberstoexcludeEditFieldLabel.Text = {'parcellation'; 'numbers to exclude'};
            app.parcellationnumberstoexcludeEditField = uieditfield(app.AddAtlasUIFigure, 'text');
            app.parcellationnumberstoexcludeEditField.Placeholder = '[10,12,65:100,125] (example)';
            app.parcellationnumberstoexcludeEditField.Value = '[0]';
            app.atlas_selected_txt = uieditfield(app.AddAtlasUIFigure, 'text');
            app.atlas_selected_txt.Editable = 'off';
            app.atlas_selected_nifti = uieditfield(app.AddAtlasUIFigure, 'text');
            app.atlas_selected_nifti.Editable = 'off';
            % Show the figure after all components are created
            app.AddAtlasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppAddAtlas(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddAtlasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddAtlasUIFigure)
        end
    end
end