classdef DialogAppConnectoMeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Fixcolorscale          matlab.ui.control.CheckBox
        ColorlimitSlider       matlab.ui.control.RangeSlider
        ColorlimitSliderLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        ConnectoMEG; % Main app
        %ctx = wjn_mni_cortex;       % Cortex
        p; %patch figure main mesh
        pp; %patch figure small regio sphere
        pp_vertices; %patch vertices standard centered around 0
        cursorMode;
        lgt; %light mode
    end
    methods (Access = public)
        
        function output_txt = UpdateDatatip(app,src,event,name)
            pos = get(event,'Position');
            output_txt = {['X: ',num2str(pos(1),'%.1f')],...
            ['Y: ',num2str(pos(2),'%.1f')],...
            ['Z: ',num2str(pos(3),'%.1f')]};
            if ~isequal([round(pos(1),1),round(pos(2),1),round(pos(3),1)],app.ConnectoMEG.ROIs.coord_interest)
                app.ConnectoMEG.MNIx.Value = round(pos(1),1);
                app.ConnectoMEG.MNIy.Value = round(pos(2),1);
                app.ConnectoMEG.MNIz.Value = round(pos(3),1);
                app.ConnectoMEG.AtlasDropDown.Value='None';
                app.ConnectoMEG.LabelDropDown.Value='None';
                app.ConnectoMEG.ROIs = app.ConnectoMEG.ROIs.set_coord_interest([round(pos(1),1),round(pos(2),1),round(pos(3),1)]);
                app.pp.Vertices = app.pp_vertices(:,:) + [app.ConnectoMEG.MNIx.Value, app.ConnectoMEG.MNIy.Value, app.ConnectoMEG.MNIz.Value];
            end
            app.ConnectoMEG.updateDialogue();
        end

        function setcolorlimits(app, ba_trans)
            Vmin=[-1000,-500,-200,-100, -25,-20,-15,-10,-5,-4,-3,-2,-1.5,-1,-0.75,-.5,-0.4,-0.3,-0.25,-0.2,-0.15,-0.1,-0.075,-0.05,-0.04,-0.03,-0.025,-0.02,-0.015,-0.01,0,1000]; %add 1000 to hack the interpolation at the ends
            Vmax=-(Vmin);
            cmin = interp1(Vmin,Vmin,min(ba_trans),'nearest');
            cmax = interp1(Vmax,Vmax,max(ba_trans),'nearest');
            if strcmp(app.ConnectoMEG.conn_metric, 'icoh')
                tmp=sort(ba_trans, 'ascend');
                cmax=tmp(round(length(ba_trans)*0.001)); %set the cmax equal top 0.1% percentile
                cmax = interp1(Vmax,Vmax,cmax,'nearest');
                if cmax == cmin
                    cmax = abs(Vmin(find(Vmin==cmin)-1)); %if cmax equals cmin, go to the next color limit
                end
                if cmax<0.05
                    cmax=0.05;
                end
            end
            
            [mi,ma]=clim(app.UIAxes); %retrieve the current color values of the plot
            if cmax<mi; cmax = abs(Vmax(find(Vmax==cmax)-1)); end %increase the cmax if it was chosen too low compared to mininum color value, keep scale at 0
            if cmax<mi; cmax = abs(Vmax(find(Vmax==cmax)-1)); end %increase again the cmax if it was chosen too low compared to mininum color value, keep scale at 0
            if cmax<mi; cmax = abs(Vmax(find(Vmax==cmax)-1)); end %increase again the cmax if it was chosen too low compared to mininum color value, keep scale at 0
            if mi>0.025; mi=0.025; end
            
            app.ColorlimitSlider.Limits=[cmin,cmax];
            if mi<cmin; mi=cmin; end
            if ma>cmax; ma=cmax; end
            
            app.ColorlimitSlider.Value=[mi,ma];
            clim(app.UIAxes,[mi,ma]);

        end

        function setpercentagelimits(app, ba_trans_perc)
            % set the color scale to -100 100
            %Vmin=[-25,-20,-15,-10,-5,0,100];
            %Vmax=-(Vmin);
            cmin = -100;
            cmax = 100;
            app.ColorlimitSlider.Limits=[-100,100];
            [mi,ma]=clim(app.UIAxes);
            if mi<cmin; mi=cmin; end
            if ma>cmax; ma=cmax; end
            app.ColorlimitSlider.Value=[-100,100];
        end
        
        function showpercentdiff(app, ba_trans_perc)
            app.ConnectoMEG.ba_trans_perc = ba_trans_perc;
            ba_trans_perc(ba_trans_perc>=100)=99;
            ba_trans_perc(ba_trans_perc<=-100)=-99;
            app.p.FaceVertexCData=ba_trans_perc ;
            disp('update done')
            
            c=colormap(app.UIAxes,'cool');
           
            % handle NaN values
            colormap(app.UIAxes,[.5 .5 .5;c]);
            app.p.FaceVertexCData(isnan(app.p.FaceVertexCData))= -101;%-(1/257)+min(app.p.FaceVertexCData);
             
            if app.Fixcolorscale.Value
                clim(app.UIAxes,[app.ColorlimitSlider.Value]);
            else
                if all(app.p.FaceVertexCData==0)
                    clim(app.UIAxes,[-100 100])
                elseif ~(isnan(min(app.p.FaceVertexCData)))
                    %app.p.FaceVertexCData
                    clim(app.UIAxes,[min(app.p.FaceVertexCData) max(app.p.FaceVertexCData)])
                    %setpercentagelimits(app,ba_trans_perc);
                end
            end
            clim(app.UIAxes,[-100 100])
            setpercentagelimits(app, ba_trans_perc)
        end

        function showtmap(app, ba_tvals_trans)
            app.ConnectoMEG.ba_tvals_trans = ba_tvals_trans;
            ba_tvals_trans(ba_tvals_trans>=10)=9.5;
            ba_tvals_trans(ba_tvals_trans<=-10)=-9.5;
            app.p.FaceVertexCData=ba_tvals_trans ;
            disp('update done')
            
            c=colormap(app.UIAxes,'redblue');
            colorbar(app.UIAxes,'Color','white');
            % handle NaN values
            colormap(app.UIAxes,[.5 .5 .5;c]);
            app.p.FaceVertexCData(isnan(app.p.FaceVertexCData))= -10.5;%-(1/257)+min(app.p.FaceVertexCData);
             
            if app.Fixcolorscale.Value
                clim(app.UIAxes,[app.ColorlimitSlider.Value]);
            else
                if or(all(app.p.FaceVertexCData),sum(app.p.FaceVertexCData==0))
                    clim(app.UIAxes,[-10 10])
                elseif ~(isnan(min(app.p.FaceVertexCData)))
                    %app.p.FaceVertexCData
                    aclim(app.UIAxes,[min(app.p.FaceVertexCData) max(app.p.FaceVertexCData)])
                end
            end
            clim(app.UIAxes,[-10 10])
            %setpercentagelimits(app, ba_trans_tvals)
        end
        
        function showpmap(app, ba_pvals_trans)
            app.ConnectoMEG.ba_pvals_trans = ba_pvals_trans;
            app.p.FaceVertexCData=ba_pvals_trans;
            app.p.FaceVertexCData(isnan(ba_pvals_trans))= 1.1;
            app.p.FaceVertexCData((ba_pvals_trans)<=1)= 0.7;
            app.p.FaceVertexCData((ba_pvals_trans)<=0.05)= 0.5;
            app.p.FaceVertexCData((ba_pvals_trans)<=0.01)= 0.3;
            app.p.FaceVertexCData((ba_pvals_trans)<=0.001)= 0.1;
            colormap(app.UIAxes,[[0.4 0 0.4];[0.7 0 0.7];[1 0 1];[1 1 1];[.5 .5 .5]]);
            clim(app.UIAxes,[0 1])
            colorbar(app.UIAxes,'XTickLabel',{'0','0.001','0.01','0.05','1','NaN'}, ...
               'XTick', [0,0.2,0.4,0.6,0.8,1],'Color','white')
            disp('update done')
        end

        function updateplot(app, ba_trans)
            % Store inputs as properties
            %if strcmp(app.ConnectoMEG.conn_metric, 'icoh')
            %    ba_trans(ba_trans==1) = NaN;
            %end
            app.ConnectoMEG.ba_trans = ba_trans;
            % Update plot
            %if sum(isnan(ba_trans)) == length(ba_trans)
            %    app.p.FaceVertexCData
            %else
            app.p.FaceVertexCData=app.ConnectoMEG.ba_trans ;
        
            disp('update done')
            c=colormap(app.UIAxes,'parula');
           
            if app.ConnectoMEG.signif
                colormap(app.UIAxes,[.5 .5 .5;c]);
                app.p.FaceVertexCData(isnan(app.p.FaceVertexCData))=-(1/257)+min(app.p.FaceVertexCData);
            end
            %end
            
            %% fix clim for NAN values
            if app.Fixcolorscale.Value
                clim(app.UIAxes,[app.ColorlimitSlider.Value]);
            else
                if ~isnan(min(app.p.FaceVertexCData))
                    %app.p.FaceVertexCData
                    clim(app.UIAxes,[min(app.p.FaceVertexCData) max(app.p.FaceVertexCData)])
                    setcolorlimits(app,ba_trans);
                end
            end

            %if strcmp(app.ConnectoMEG.conn_metric, 'grang')
            %    clim(app.UIAxes,[(-1/257-1) 1]);
            %    
            %else
            %    clim(app.UIAxes,[-1/257 1]);
            %    app.p.FaceVertexCData(isnan(app.p.FaceVertexCData))=-(1/257);
            %end
            %colorbar(app.UIAxes,'Color','white');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, caller, colorname)
            arguments
                app
                caller
                colorname = 'parula'
            end
            % Store main app in property for CloseRequestFcn to use
            app.ConnectoMEG = caller;
            %app.ctx = export(gifti('cortex_20484.surf.gii'));
            [app.p,~,app.ConnectoMEG.ba_trans]=jvh_plot_surface('',app.ConnectoMEG.ba_trans, app.UIAxes);
            axis(app.UIAxes,"equal")
            app.lgt = camlight(app.UIAxes);
            set(app.UIFigure,'color','k')
            set(app.UIAxes,'visible','off')
            c=colormap(app.UIAxes,colorname);
            
            %if strcmp(app.ConnectoMEG.conn_metric, 'grang')
            %    clim(app.UIAxes,[(-1/257-1) 1]);
            %    app.p.FaceVertexCData(isnan(app.p.FaceVertexCData))=-(1/257)-1;
            %else
            %    clim(app.UIAxes,[-1/257 1]);
            if app.ConnectoMEG.signif
                colormap(app.UIAxes,[.5 .5 .5;c]);
                app.p.FaceVertexCData(isnan(app.p.FaceVertexCData))=-(1/257)+min(app.p.FaceVertexCData);
            end
            %end
            colorbar(app.UIAxes,'Color','white');
            app.cursorMode = datacursormode(app.UIFigure);
            app.cursorMode.Enable="on";
            app.cursorMode.UpdateFcn = @app.UpdateDatatip;%@(app)UpdateDatatip(app, varargin{:})%@app.UpdateDatatip;% @ButtonPushed;%createCallbackFcn(app, @DialogAppCloseRequest, true)%@(src,event)UpdateDatatip(src,event);%set(app.cursorMode, 'UpdateFcn',@app.UpdateDatatip); 
            app.cursorMode.DisplayStyle='window';
            app.cursorMode.Figure.Color =[0 0 0];
            set(rotate3d(app.UIFigure),'ActionPostCallback',@(x,y) camlight(app.lgt));
            
            setcolorlimits(app,app.ConnectoMEG.ba_trans)

            app.pp=jvh_plot_surface('current_sphere.surf.gii',[1 1 1], app.UIAxes); %hide  sphere in the middle of the brain
            app.pp_vertices = app.pp.Vertices;
            % move the sphere to the target mni
            %for i=range(1:size(app.ConnectoMEG.ROIs.coord_closest,1))
            %    c=app.ConnectoMEG.ROIs.coord_closest(i,:);
            %    app.pp=jvh_plot_surface('current_sphere.surf.gii',[1 1 1], app.UIAxes);
            %    app.pp_vertices = app.pp.Vertices;
            %    app.pp.Vertices = app.pp_vertices(:,:) + c;
            %end
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Options button in main app, if the app is still open
            try
                if isvalid(app.ConnectoMEG)
                     app.ConnectoMEG.InteractivemodeButton.Enable = "on";
                end
            catch
            end
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: ColorlimitSlider
        function ColorlimitSliderValueChanged(app, event)
            value = app.ColorlimitSlider.Value;
            disp(value)
            clim(app.UIAxes,value);
        end

        % Value changed function: Fixcolorscale
        function FixcolorscaleValueChanged(app, event)
            value = app.Fixcolorscale.Value;
            alwaysfix=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Interactive Oscillatory Brain Network Mapper';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ColorlimitSliderLabel = uilabel(app.UIFigure);
            app.ColorlimitSliderLabel.Text = 'Color limit';
            app.ColorlimitSlider = uislider(app.UIFigure, 'range');
            app.ColorlimitSlider.Limits = [0 1];
            app.ColorlimitSlider.ValueChangedFcn = createCallbackFcn(app, @ColorlimitSliderValueChanged, true);
            app.ColorlimitSlider.Value = [0 1];
            app.Fixcolorscale = uicheckbox(app.UIFigure);
            app.Fixcolorscale.ValueChangedFcn = createCallbackFcn(app, @FixcolorscaleValueChanged, true);
            app.Fixcolorscale.Text = 'Fix colorscale';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppConnectoMeg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end