classdef DialogAppGenetics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AtlasButtonGroup              matlab.ui.container.ButtonGroup
        DesikanKillianyButton         matlab.ui.control.RadioButton
        NoneButton                    matlab.ui.control.RadioButton
        ShowgeneticcorrelationButton  matlab.ui.control.Button
        GOIListBox                    matlab.ui.control.ListBox
        GOIselectedLabel              matlab.ui.control.Label
        GOIEditField                  matlab.ui.control.EditField
        GOIEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        gene_abbreviations = readtable('gene_abbreviations.csv'); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GOIListBox
        function GOIListBoxChanged(app, event)
             value1 = app.GOIListBox.Value;
           
            persistent chk
            if isempty(chk)
                  chk = 1;
                  pause(0.5); %Add a delay to distinguish single click from a double click
                  if chk == 1
                      %fprintf(1,'\nI am doing a single-click.\n\n');
                      app.GOIEditField.Value = value1;
                      chk = [];
                  end
            %else
            %      chk = [];
            %      fprintf(1,'\nI am doing a double-click.\n\n');
            end
        end

        % Callback function: GOIEditField, GOIEditField
        function GOIEditFieldValueChanging(app, event)
            [GOI_hit_list_symbol, GOI_hit_list_name] = GOI_search_suggestions(event.Value, app.gene_abbreviations);
            app.GOIListBox.Items=GOI_hit_list_symbol;
        end

        % Button pushed function: ShowgeneticcorrelationButton
        function ShowgeneticcorrelationButtonPushed(app, event)
            updateConnectoMEG(app);
            GOI_selected=app.GOIEditField.Value;
            atlas=app.AtlasButtonGroup.SelectedObject.Text;
            
            mode                =   app.ModeButtonGroup.SelectedObject.Text;
            disp(' ... busy ...')

            [app.CM, app.freq]=retrieve_RMap_GOI(app.cond, app.conn_metric, app.conn_nodes, app.dataset, app.FOIs, app.ROIs, app.norm, mode, GOI_selected, atlas, app.CMavg, app.CM);
            disp('Done!')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GOIEditFieldLabel = uilabel(app.UIFigure);
            app.GOIEditFieldLabel.Text = 'GOI';
            app.GOIEditField = uieditfield(app.UIFigure, 'text');
            app.GOIEditField.ValueChangedFcn = createCallbackFcn(app, @GOIEditFieldValueChanging, true);
            app.GOIEditField.ValueChangingFcn = createCallbackFcn(app, @GOIEditFieldValueChanging, true);
            app.GOIselectedLabel = uilabel(app.UIFigure);
            app.GOIselectedLabel.Text = 'GOI selected';
            app.GOIListBox = uilistbox(app.UIFigure);
            app.GOIListBox.Items = {'GOI'};
            app.GOIListBox.ValueChangedFcn = createCallbackFcn(app, @GOIListBoxChanged, true);
            app.GOIListBox.Value = 'GOI';
            app.ShowgeneticcorrelationButton = uibutton(app.UIFigure, 'push');
            app.ShowgeneticcorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowgeneticcorrelationButtonPushed, true);
            app.ShowgeneticcorrelationButton.Text = 'Show genetic correlation';
            app.AtlasButtonGroup = uibuttongroup(app.UIFigure);
            app.AtlasButtonGroup.Title = 'Atlas';
            app.NoneButton = uiradiobutton(app.AtlasButtonGroup);
            app.NoneButton.Text = 'None';
            app.DesikanKillianyButton = uiradiobutton(app.AtlasButtonGroup);
            app.DesikanKillianyButton.Text = 'Desikan-Killiany';
            app.DesikanKillianyButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppGenetics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end